classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RaporlaButton        matlab.ui.control.Button
        Eklem6EditField      matlab.ui.control.NumericEditField
        Slider6ValueLabelEditFieldLabel  matlab.ui.control.Label
        Eklem5EditField      matlab.ui.control.NumericEditField
        Slider5ValueLabelEditFieldLabel  matlab.ui.control.Label
        Eklem4EditField      matlab.ui.control.NumericEditField
        Slider4ValueLabelEditFieldLabel  matlab.ui.control.Label
        Eklem3EditField      matlab.ui.control.NumericEditField
        Slider3ValueLabelEditFieldLabel  matlab.ui.control.Label
        Eklem2EditField      matlab.ui.control.NumericEditField
        Slider2ValueLabelEditFieldLabel  matlab.ui.control.Label
        Eklem1EditField      matlab.ui.control.NumericEditField
        Slider1ValueLabelEditFieldLabel  matlab.ui.control.Label
        GeriKinematikButton  matlab.ui.control.Button
        ZEditField           matlab.ui.control.NumericEditField
        ZEditFieldLabel      matlab.ui.control.Label
        YEditField           matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        XEditField           matlab.ui.control.NumericEditField
        XEditFieldLabel      matlab.ui.control.Label
        leriKinematikButton  matlab.ui.control.Button
        Slider6              matlab.ui.control.Slider
        Slider6Label         matlab.ui.control.Label
        Slider5              matlab.ui.control.Slider
        Slider5Label         matlab.ui.control.Label
        Slider4              matlab.ui.control.Slider
        Slider4Label         matlab.ui.control.Label
        Slider3              matlab.ui.control.Slider
        Slider3Label         matlab.ui.control.Label
        Slider2              matlab.ui.control.Slider
        Slider2Label         matlab.ui.control.Label
        Slider1              matlab.ui.control.Slider
        Slider1Label         matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: leriKinematikButton
        function leriKinematikButtonPushed(app, event)
  
    theta = [app.Slider1.Value, app.Slider2.Value, app.Slider3.Value, ...
             app.Slider4.Value, app.Slider5.Value, app.Slider6.Value] * pi/180;

    % Pozisyonları al
    positions = forward_kinematics_positions(theta);  % 3x7 matrix

    % Ekranı temizle ve robotu çiz
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');

    plot3(app.UIAxes, positions(1,:), positions(2,:), positions(3,:), ...
        '-o', 'LineWidth', 2, 'MarkerSize', 6, 'Color', [0 0.4470 0.7410]);

    
    for i = 1:6
        txt = ['Joint ', num2str(i)];
        text(app.UIAxes, positions(1,i), positions(2,i), positions(3,i)+50, ...
            txt, 'FontSize', 9, 'Color', 'black');
    end

    % Uç efektörü
    plot3(app.UIAxes, positions(1,end), positions(2,end), positions(3,end), ...
        'rp', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
    text(app.UIAxes, positions(1,end), positions(2,end), positions(3,end)+50, ...
        'End Effector', 'FontSize', 10, 'Color', 'red', 'FontWeight', 'bold');

    % Eksen ayarları
    xlabel(app.UIAxes, 'X'); ylabel(app.UIAxes, 'Y'); zlabel(app.UIAxes, 'Z');
    title(app.UIAxes, 'KUKA KR 5 sixx R650 - İleri Kinematik');
    grid(app.UIAxes, 'on'); axis(app.UIAxes, 'equal'); view(app.UIAxes, 3);
    xlim(app.UIAxes, [-2000 2000]); ylim(app.UIAxes, [-2000 2000]); zlim(app.UIAxes, [0 3000]);

    hold(app.UIAxes, 'off');


        end

        % Button pushed function: GeriKinematikButton
        function GeriKinematikButtonPushed(app, event)


    goalPos = [app.XEditField.Value; app.YEditField.Value; app.ZEditField.Value];

    
    guess = [app.Slider1.Value, app.Slider2.Value, app.Slider3.Value, ...
             app.Slider4.Value, app.Slider5.Value, app.Slider6.Value] * pi/180;

    % Geri kinematik çöz
    thetas = inverse_kinematics(goalPos, guess);

    % Animasyonla geçiş
    animate_motion(app, guess, thetas, 50);

    for i = 1:6
        deg = thetas(i) * 180/pi;
        slider = app.(['Slider' num2str(i)]);
        minLimit = slider.Limits(1);
        maxLimit = slider.Limits(2);

        % Limit kontrolü
        slider.Value = max(min(deg, maxLimit), minLimit);

        % Eğer varsa yanındaki kutuyu da güncelle
        try
            app.(['Slider' num2str(i) 'Deger']).Value = slider.Value;
        catch
           
        end

    end


        end

        % Callback function
        function Slider1ValueChanged(app, event)
           
            
        end

        % Value changed function: Slider1
        function Slider1ValueChanging(app, event)
           
             app.Eklem1EditField.Value = app.Slider1.Value;
        end

        % Value changed function: Slider2
        function Slider2ValueChanging(app, event)
           app.Eklem2EditField.Value = app.Slider2.Value;
        end

        % Value changed function: Slider3
        function Slider3ValueChanging(app, event)
            app.Eklem3EditField.Value = app.Slider3.Value;
            
        end

        % Value changed function: Slider4
        function Slider4ValueChanging(app, event)
            app.Eklem4EditField.Value = app.Slider4.Value;
            
        end

        % Value changed function: Slider5
        function Slider5ValueChanging(app, event)
             app.Eklem5EditField.Value = app.Slider5.Value;
            
        end

        % Value changed function: Slider6
        function Slider6ValueChanging(app, event)
            app.Eklem6EditField.Value = app.Slider6.Value;
            
        end

        % Button pushed function: RaporlaButton
        function RaporlaButtonPushed(app, event)
            
    % Açıları oku (derece)
    theta_deg = [app.Slider1.Value, app.Slider2.Value, app.Slider3.Value, ...
                 app.Slider4.Value, app.Slider5.Value, app.Slider6.Value];

    % Radyan cinsine çevir
    theta_rad = theta_deg * pi / 180;

    % Uç efektör pozisyonunu al
    T = forward_kinematics(theta_rad);
    pos = T(1:3,4); % X, Y, Z

    % CSV verisi hazırlama
    header = {'Theta1 (°)', 'Theta2', 'Theta3', 'Theta4', 'Theta5', 'Theta6', ...
              'X', 'Y', 'Z'};
    data = [theta_deg, pos'];

    % Kullanıcıdan dosya adı iste
    [file, path] = uiputfile('*.csv', 'Raporu Kaydet');
    if isequal(file, 0)
        return; % İptal edildi
    end

    % Dosyaya yaz
    fullPath = fullfile(path, file);
    fid = fopen(fullPath, 'w');
    fprintf(fid, '%s,%s,%s,%s,%s,%s,%s,%s,%s\n', header{:});
    fprintf(fid, '%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f\n', data);
    fclose(fid);

    % Başarılı mesaj
    uialert(app.UIFigure, 'Rapor başarıyla kaydedildi!', 'Tamam');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Slider1Label = uilabel(app.UIFigure);
            app.Slider1Label.Text = 'Slider1';
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.Limits = [-180 180];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanging, true);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [-180 180];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [-180 180];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.Slider4Label = uilabel(app.UIFigure);
            app.Slider4Label.Text = 'Slider4';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Limits = [-180 180];
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @Slider4ValueChanging, true);
            app.Slider5Label = uilabel(app.UIFigure);
            app.Slider5Label.Text = 'Slider5';
            app.Slider5 = uislider(app.UIFigure);
            app.Slider5.Limits = [-180 180];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanging, true);
            app.Slider6Label = uilabel(app.UIFigure);
            app.Slider6Label.Text = 'Slider6';
            app.Slider6 = uislider(app.UIFigure);
            app.Slider6.Limits = [-180 180];
            app.Slider6.ValueChangedFcn = createCallbackFcn(app, @Slider6ValueChanging, true);
            app.leriKinematikButton = uibutton(app.UIFigure, 'push');
            app.leriKinematikButton.ButtonPushedFcn = createCallbackFcn(app, @leriKinematikButtonPushed, true);
            app.leriKinematikButton.Text = 'İleri Kinematik';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'XEditField';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'YEditField';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'ZEditField';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeriKinematikButton = uibutton(app.UIFigure, 'push');
            app.GeriKinematikButton.ButtonPushedFcn = createCallbackFcn(app, @GeriKinematikButtonPushed, true);
            app.GeriKinematikButton.Text = 'Geri Kinematik';
            app.Slider1ValueLabelEditFieldLabel = uilabel(app.UIFigure);
            app.Slider1ValueLabelEditFieldLabel.Text = 'Eklem1';
            app.Eklem1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Slider2ValueLabelEditFieldLabel = uilabel(app.UIFigure);
            app.Slider2ValueLabelEditFieldLabel.Text = 'Eklem2';
            app.Eklem2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Slider3ValueLabelEditFieldLabel = uilabel(app.UIFigure);
            app.Slider3ValueLabelEditFieldLabel.Text = 'Eklem3';
            app.Eklem3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Slider4ValueLabelEditFieldLabel = uilabel(app.UIFigure);
            app.Slider4ValueLabelEditFieldLabel.Text = 'Eklem4';
            app.Eklem4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Slider5ValueLabelEditFieldLabel = uilabel(app.UIFigure);
            app.Slider5ValueLabelEditFieldLabel.Text = 'Eklem5';
            app.Eklem5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Slider6ValueLabelEditFieldLabel = uilabel(app.UIFigure);
            app.Slider6ValueLabelEditFieldLabel.Text = 'Eklem6';
            app.Eklem6EditField = uieditfield(app.UIFigure, 'numeric');
            app.RaporlaButton = uibutton(app.UIFigure, 'push');
            app.RaporlaButton.ButtonPushedFcn = createCallbackFcn(app, @RaporlaButtonPushed, true);
            app.RaporlaButton.Text = 'Raporla';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end